classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        ButtonGroup    matlab.ui.container.ButtonGroup
        Button         matlab.ui.control.RadioButton
        Button_2       matlab.ui.control.RadioButton
        Button_3       matlab.ui.control.RadioButton
        ButtonGroup_3  matlab.ui.container.ButtonGroup
        Button_6       matlab.ui.control.RadioButton
        Button_7       matlab.ui.control.RadioButton
        ButtonGroup_4  matlab.ui.container.ButtonGroup
        Button_8       matlab.ui.control.RadioButton
        Button_9       matlab.ui.control.RadioButton
        Button_10      matlab.ui.control.Button
    end

    methods (Access = private)

        % Button pushed function: Button_10
        function mainAlgoritm(app, event)
            %Обработчик нажатия кнопки
            %Определение типа ввода матрицы
            %Ввод матрицы из индивидуального варианта
            if app.Button.Value
                InputType = 1;
                %Ввод матрицы случайной матрицы заданной размерности
            elseif app.Button_2.Value
                InputType = 2;
                %Ручной ввод матрицы
            else
                InputType = 3;
            end
            %Определение типа задачи
            %Задача минимизации
            if app.Button_6.Value
                TaskType = 1;
                %Задача максимизации
            else
                TaskType = 2;
            end
            %Определение типа вывода
            %В отладочном режиме
            if app.Button_8.Value
                ShowType = 1;
                %Выводить только ответ
            else
                ShowType = 2;
            end
            %Вызов основной функции обработки
            mainAlgoritm(InputType,TaskType,ShowType);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'Венгерский Алгоритм. Суворов Д.С. ИУ7-13м';
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.Title = 'Тип ввода:';
            app.Button = uiradiobutton(app.ButtonGroup);
            app.Button.Text = 'Матрица из варианта';
            app.Button.Value = true;
            app.Button_2 = uiradiobutton(app.ButtonGroup);
            app.Button_2.Text = 'Случайные числа';
            app.Button_3 = uiradiobutton(app.ButtonGroup);
            app.Button_3.Text = 'Ручной ввод';
            app.ButtonGroup_3 = uibuttongroup(app.UIFigure);
            app.ButtonGroup_3.Title = 'Тип задачи:';
            app.Button_6 = uiradiobutton(app.ButtonGroup_3);
            app.Button_6.Text = 'Минимизация';
            app.Button_6.Value = true;
            app.Button_7 = uiradiobutton(app.ButtonGroup_3);
            app.Button_7.Text = 'Максимизация';
            app.ButtonGroup_4 = uibuttongroup(app.UIFigure);
            app.ButtonGroup_4.Title = 'Режим отображения:';
            app.Button_8 = uiradiobutton(app.ButtonGroup_4);
            app.Button_8.Text = 'Показать ход решения';
            app.Button_8.Value = true;
            app.Button_9 = uiradiobutton(app.ButtonGroup_4);
            app.Button_9.Text = 'Показать только ответ';
            app.Button_10 = uibutton(app.UIFigure, 'push');
            app.Button_10.ButtonPushedFcn = createCallbackFcn(app, @mainAlgoritm, true);
            app.Button_10.Text = 'Ввод матрицы';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end